classdef projectile_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResetButton               matlab.ui.control.Button
        ResultTextArea            matlab.ui.control.TextArea
        ResultTextAreaLabel       matlab.ui.control.Label
        AnglesliderSliderLabel    matlab.ui.control.Label
        SimulateButton            matlab.ui.control.Button
        AnglesliderSlider         matlab.ui.control.Slider
        BuildingheightHEditField  matlab.ui.control.NumericEditField
        BuildingheightHLabel      matlab.ui.control.Label
        AzEditField               matlab.ui.control.NumericEditField
        AzEditFieldLabel          matlab.ui.control.Label
        AyEditField               matlab.ui.control.NumericEditField
        AyEditFieldLabel          matlab.ui.control.Label
        AxEditField               matlab.ui.control.NumericEditField
        AxLabel                   matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % to be used between callbacks of different Fields 
        % to prevent re-defining variables from previous callbacks
        sharable_variable_alpha_min 
        sharable_variable_alpha_max
        sharable_variable_R
        sharable_variable_z
        sharable_variable_S
        sharable_variable_g
        sharable_variable_H
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
uialert(app.UIFigure, 'Please enter the coordinates of launching area, A and the height of building, H, all in meters', 'Tip','icon','info');
% Make sure user doesn't use the slider before simulating
app.AnglesliderSlider.Enable = 'off';

        end

        % Value changed function: AnglesliderSlider
        function AnglesliderSliderValueChanged(app, event)
            % This callback is done after the 'Stimulate_Button' callback

            % Defining the final position of the slider as the chosen angle to be used
            user_angle = app.AnglesliderSlider.Value;
            
            app.AnglesliderSlider.Limits = [app.sharable_variable_alpha_min,app.sharable_variable_alpha_max];
                 
            
            % Convert user chosen angle to radians
            user_angle_rad = deg2rad(user_angle);
            
            % Calculate velocity for chosen angle
            denominator_user = 2 * (cos(user_angle_rad))^2 * (app.sharable_variable_R * tan(user_angle_rad) + app.sharable_variable_z);
            V_user = sqrt((app.sharable_variable_g * app.sharable_variable_R^2) / denominator_user);
            
            % Check if the projectile clears the building
            t_building = app.sharable_variable_S / (V_user * cos(user_angle_rad));
            z_building = app.sharable_variable_z + V_user * sin(user_angle_rad) * t_building - 0.5 * app.sharable_variable_g * t_building^2;
            
            
            % Display chosen values
            uialert(app.UIFigure, ...
                sprintf('Chosen angle: %.2f degrees\nCalculated velocity: %.2f m/s', user_angle, V_user),'Confirmation','icon','info');
            
            % Plot trajectory
            
            % Total duratiom for chosen angle
            t_total = app.sharable_variable_R / (V_user * cos(user_angle_rad));
            % Time vector created for plot
            t = linspace(0, t_total, 100);
            % Height at given t
            z_t = app.sharable_variable_z + V_user * sin(user_angle_rad) * t - 0.5 * app.sharable_variable_g * t.^2;
            
            % Time at which object is above building 
            t_over_building = app.sharable_variable_S / (V_user * cos(user_angle_rad));
            
            % Building Marker
            % Building location on graph
            hold (app.UIAxes,'on');
            line(app.UIAxes,[t_over_building t_over_building], [0 app.sharable_variable_H], 'Color', 'g', 'LineStyle', '-', 'LineWidth', 5);
            text(app.UIAxes, t_over_building + 0.05, app.sharable_variable_H, 'Building');
            
            %projectile motion plot
            plot(app.UIAxes, t, z_t, 'b', 'LineWidth', 2);
            xlim(app.UIAxes,[0 max(t)]);
            ylim(app.UIAxes,[0 max(z_t) + 5]);
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Height (m)');
            title(app.UIAxes, sprintf('Projectile Height vs Time (Angle: %.2f°)', user_angle));
            grid (app.UIAxes,'on');
            hold (app.UIAxes,'off');
            
            % To prevent user to change the pointer of slider
            % Thus preventing overlap of plots of different angles
            app.AnglesliderSlider.Enable = 'off';

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Coordinates of launching area (A)
            % Assigning user entered coordinates of A to respective Field
            
            x = app.AxEditField.Value;
            if  x < 0
            uialert(app.UIFigure, 'Invalid input. Please enter a positive number.', 'Input Error');
            return;
            end
            
            y = app.AyEditField.Value;
            if  y < 0
            uialert(app.UIFigure, 'Invalid input. Please enter a positive number.', 'Input Error');
            return;
            end
            
            z = app.AzEditField.Value;
            app.sharable_variable_z=z;
            if  z < 0
            uialert(app.UIFigure, 'Invalid input. Please enter a positive number.', 'Input Error');
            return;
            end
            
            H = app.BuildingheightHEditField.Value;
            app.sharable_variable_H=H;
            if H < 0
            uialert(app.UIFigure, 'Invalid input. Please enter a positive number.', 'Input Error');
            return;
            end
            
            % A combination of coordinates to avoid 
            if y==0 && x==0
             uialert(app.UIFigure, 'Invalid input. Please enter a set of coordinates away from the building.', 'Input Error');
            return;
            end
            
            
            % Fixed coordinates of landing point (B)
            x_B = -10;
            y_B = -5;
            z_B = 0;
            
            % Constant acceleration due to gravity (m/s^2)
            g = 9.81;
            app.sharable_variable_g=g;
            
            % Horizontal distance from A to B
            R = sqrt((x - x_B)^2 + (y - y_B)^2);
            app.sharable_variable_R=R;
            
            % Horizontal distance from A to building at (0,0)
            S = sqrt(x^2 + y^2);
            app.sharable_variable_S=S;
            
            % Validation checks
            if R == 0
            uialert(app.UIFigure, 'Error: Launch and landing points are the same.', 'Input Error');
            return
            end
            
            if S == 0
            uialert(app.UIFigure, 'Error: Launch and building coordinates are the same.', 'Input Error');
            return
            end
            
            % Variables to store best angles and velocities 
            alpha_min = 1000;
            alpha_max = 0;
            v_min = 0;
            v_max = 0;
            
            % Loop over angles from 1 to 89 degrees 
            for alpha = 1:0.1:89
                alpha_rad = deg2rad(alpha);
            
                % Detailed velocity formula
                % Checks to see if denomiator is positive for alpha
                % If not positive, next alpha is used 
                denominator = 2 * (cos(alpha_rad))^2 * (R * tan(alpha_rad) + z);
                if denominator <= 0
                continue;
                end
                v = sqrt((g * R^2) / denominator);
            
                % Time to reach the building horizontally
                t2 = S / (v * cos(alpha_rad));
            
                % Height of object when it passes over the building
                zmax = z + v * sin(alpha_rad) * t2 - 0.5 * g * t2^2;
            
                % Update min and max angles
                if zmax > H
                    if alpha < alpha_min
                        alpha_min = alpha;
                        v_min = v;
                    end
                    if alpha > alpha_max
                        alpha_max = alpha;
                        v_max = v;
                    end
                    
                end
            end
            
            app.sharable_variable_alpha_min=alpha_min;
            app.sharable_variable_alpha_max=alpha_max;
            
            % Display results
            if alpha_min == 1000
            uialert(app.UIFigure, 'No feasible angle found to clear the building.', 'Error');
            else
              app.ResultTextArea.Value = {
                    sprintf('Minimum angle: %.2f°', alpha_min);
                    sprintf('Maximum angle: %.2f°', alpha_max);
                    sprintf('***');
                    sprintf('Minimum velocity: %.2f m/s', v_min);
                    sprintf('Maximum velocity: %.2f m/s', v_max);
                    sprintf('***');
                    sprintf('Valid angle range: %.2f° to %.2f°', alpha_min, alpha_max)
                };
            
            end
            
            % Prompt user to choose an angle within the valid range
            uialert(app.UIFigure, 'Please choose a value within the valid range.', 'Tip','icon','info');
            
            % Setting limits for the slider
            % Defining the initial position of slider pointer
            app.AnglesliderSlider.Limits = [app.sharable_variable_alpha_min, app.sharable_variable_alpha_max];
            app.AnglesliderSlider.Value = app.sharable_variable_alpha_min;
            
            % Enabling slider to be usable by user
            app.AnglesliderSlider.Enable = 'on';
        
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % When user presses Reset button-
                
            % Reset numeric input fields back to zero
            app.AxEditField.Value = 0;
            app.AyEditField.Value = 0;
            app.AzEditField.Value = 0;
            app.BuildingheightHEditField.Value = 0;
            
            % Reset slider to match your design
            app.AnglesliderSlider.Limits = [0, 100];   % limits are resetted
            app.AnglesliderSlider.Value = 0;           % slider pointer set at 0
            
            % Clear result text area correctly
            app.ResultTextArea.Value = {''};
            
            % Resets graph plot
            cla(app.UIAxes);  % clears graph simulated
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Projectile Height vs Time');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Height (m)');
            
            uialert(app.UIFigure, 'App has been reset', 'App reset', 'Icon', 'info');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'summer');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Height Vs Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Height (m)')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'summer')
            app.AxLabel = uilabel(app.UIFigure);
            app.AxLabel.Text = 'A(x)';
            app.AxEditField = uieditfield(app.UIFigure, 'numeric');
            app.AyEditFieldLabel = uilabel(app.UIFigure);
            app.AyEditFieldLabel.Text = 'A(y)';
            app.AyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AzEditFieldLabel = uilabel(app.UIFigure);
            app.AzEditFieldLabel.Text = 'A(z)';
            app.AzEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuildingheightHLabel = uilabel(app.UIFigure);
            app.BuildingheightHLabel.Text = 'Building height, H';
            app.BuildingheightHEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnglesliderSlider = uislider(app.UIFigure);
            app.AnglesliderSlider.ValueChangedFcn = createCallbackFcn(app, @AnglesliderSliderValueChanged, true);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.AnglesliderSliderLabel = uilabel(app.UIFigure);
            app.AnglesliderSliderLabel.Text = 'Angle slider';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectile_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end